classdef lightflashing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        controlPanel             matlab.ui.container.Panel
        restartButton            matlab.ui.control.Button
        intervalsEditField       matlab.ui.control.NumericEditField
        intervalsEditFieldLabel  matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        verticalButton           matlab.ui.control.RadioButton
        horizontalButton         matlab.ui.control.RadioButton
        lightPanel               matlab.ui.container.Panel
        Lamp_35                  matlab.ui.control.Lamp
        Lamp_34                  matlab.ui.control.Lamp
        Lamp_33                  matlab.ui.control.Lamp
        Lamp_32                  matlab.ui.control.Lamp
        Lamp_31                  matlab.ui.control.Lamp
        Lamp_30                  matlab.ui.control.Lamp
        Lamp_29                  matlab.ui.control.Lamp
        Lamp_28                  matlab.ui.control.Lamp
        Lamp_27                  matlab.ui.control.Lamp
        Lamp_26                  matlab.ui.control.Lamp
        Lamp_25                  matlab.ui.control.Lamp
        Lamp_24                  matlab.ui.control.Lamp
        Lamp_23                  matlab.ui.control.Lamp
        Lamp_22                  matlab.ui.control.Lamp
        Lamp_21                  matlab.ui.control.Lamp
        Lamp_20                  matlab.ui.control.Lamp
        Lamp_19                  matlab.ui.control.Lamp
        Lamp_18                  matlab.ui.control.Lamp
        Lamp_17                  matlab.ui.control.Lamp
        Lamp_16                  matlab.ui.control.Lamp
        Lamp_15                  matlab.ui.control.Lamp
        Lamp_14                  matlab.ui.control.Lamp
        Lamp_13                  matlab.ui.control.Lamp
        Lamp_12                  matlab.ui.control.Lamp
        Lamp_11                  matlab.ui.control.Lamp
        Lamp_10                  matlab.ui.control.Lamp
        Lamp_9                   matlab.ui.control.Lamp
        Lamp_8                   matlab.ui.control.Lamp
        Lamp_7                   matlab.ui.control.Lamp
        Lamp_6                   matlab.ui.control.Lamp
        Lamp_5                   matlab.ui.control.Lamp
        Lamp_4                   matlab.ui.control.Lamp
        Lamp_3                   matlab.ui.control.Lamp
        Lamp_2                   matlab.ui.control.Lamp
        Lamp_1                   matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            pause(3) % dianji kaishi yanchi 3s
            tt = app.intervalsEditField.Value;
            if app.horizontalButton.Value
                for ii=1:35
                    eval(['app.Lamp_',num2str(ii),'.Color','=','[','0,1,0',']']) ;%绿色
                     pause(tt)
                end     
            else
                numd =[];
                for kk=1:7
                    %bb=linspace(kk,kk*7+1,5)
                    for aa =1:5
                         numd=[numd,kk+7*(aa-1)];
                    end
                end
                for jj=numd
                    eval(['app.Lamp_',num2str(jj),'.Color','=','[','0,1,0',']']) ;%绿色
                     pause(tt)
                end     
                
            end
           
            
        end

        % Button pushed function: restartButton
        function restartButtonPushed(app, event)
            
                for ii=1:35
                    eval(['app.Lamp_',num2str(ii),'.Color','=','[','0,0,1',']']) ;%绿色
                 
                end     
                app.intervalsEditField.Value=1;  
            
        end

        % Callback function
        function Button_5Pushed(app, event)
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lightPanel = uipanel(app.UIFigure);
            app.lightPanel.Title = 'light';
            app.Lamp_1 = uilamp(app.lightPanel);
            app.Lamp_2 = uilamp(app.lightPanel);
            app.Lamp_3 = uilamp(app.lightPanel);
            app.Lamp_4 = uilamp(app.lightPanel);
            app.Lamp_5 = uilamp(app.lightPanel);
            app.Lamp_6 = uilamp(app.lightPanel);
            app.Lamp_7 = uilamp(app.lightPanel);
            app.Lamp_8 = uilamp(app.lightPanel);
            app.Lamp_9 = uilamp(app.lightPanel);
            app.Lamp_10 = uilamp(app.lightPanel);
            app.Lamp_11 = uilamp(app.lightPanel);
            app.Lamp_12 = uilamp(app.lightPanel);
            app.Lamp_13 = uilamp(app.lightPanel);
            app.Lamp_14 = uilamp(app.lightPanel);
            app.Lamp_15 = uilamp(app.lightPanel);
            app.Lamp_16 = uilamp(app.lightPanel);
            app.Lamp_17 = uilamp(app.lightPanel);
            app.Lamp_18 = uilamp(app.lightPanel);
            app.Lamp_19 = uilamp(app.lightPanel);
            app.Lamp_20 = uilamp(app.lightPanel);
            app.Lamp_21 = uilamp(app.lightPanel);
            app.Lamp_22 = uilamp(app.lightPanel);
            app.Lamp_23 = uilamp(app.lightPanel);
            app.Lamp_24 = uilamp(app.lightPanel);
            app.Lamp_25 = uilamp(app.lightPanel);
            app.Lamp_26 = uilamp(app.lightPanel);
            app.Lamp_27 = uilamp(app.lightPanel);
            app.Lamp_28 = uilamp(app.lightPanel);
            app.Lamp_29 = uilamp(app.lightPanel);
            app.Lamp_30 = uilamp(app.lightPanel);
            app.Lamp_31 = uilamp(app.lightPanel);
            app.Lamp_32 = uilamp(app.lightPanel);
            app.Lamp_33 = uilamp(app.lightPanel);
            app.Lamp_34 = uilamp(app.lightPanel);
            app.Lamp_35 = uilamp(app.lightPanel);
            app.controlPanel = uipanel(app.UIFigure);
            app.controlPanel.Title = 'control';
            app.ButtonGroup = uibuttongroup(app.controlPanel);
            app.horizontalButton = uiradiobutton(app.ButtonGroup);
            app.horizontalButton.Text = 'horizontal';
            app.horizontalButton.Value = true;
            app.verticalButton = uiradiobutton(app.ButtonGroup);
            app.verticalButton.Text = 'vertical';
            app.StartButton = uibutton(app.controlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.intervalsEditFieldLabel = uilabel(app.controlPanel);
            app.intervalsEditFieldLabel.Text = 'interval/s';
            app.intervalsEditField = uieditfield(app.controlPanel, 'numeric');
            app.intervalsEditField.Value = 1;
            app.restartButton = uibutton(app.controlPanel, 'push');
            app.restartButton.ButtonPushedFcn = createCallbackFcn(app, @restartButtonPushed, true);
            app.restartButton.Text = 'restart';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lightflashing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end